classdef EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        PageTitle     matlab.ui.control.Label
        Instructions  matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
        NextButton    matlab.ui.control.Button
        BackButton    matlab.ui.control.Button
        PageLabel     matlab.ui.control.Label
        ButtonGroup   matlab.ui.container.ButtonGroup
        Button_1      matlab.ui.control.RadioButton
        Button_2      matlab.ui.control.RadioButton
        Button_3      matlab.ui.control.RadioButton
        Button_4      matlab.ui.control.RadioButton
        Button_5      matlab.ui.control.RadioButton
        Button_6      matlab.ui.control.RadioButton
        Button_7      matlab.ui.control.RadioButton
        Button_8      matlab.ui.control.RadioButton
        Button_9      matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        page = 1; %The page number for Robodoc: EEG
        imgStch = 1.5;
    end


    methods (Access = private)
        
        function UpdatePageTitle(app)
            switch app.page
                case 1
                    app.PageTitle.Text = "RoboDoc: EEG";
                case 2
                    app.PageTitle.Text = "SAM: Pleasure";
                case 3
                    app.PageTitle.Text = "SAM: Arousal";
                case 4
                    app.PageTitle.Text = "SAM: Dominance";
                otherwise
                    app.PageTitle.Text = "End of Evaluation";
            end
        end
    
        function UpdateUIAxes(app)
            switch app.page
                case 1
                    % Display image and stretch to fill axes
                    I = imshow('wear_example1.jpg', 'Parent', app.UIAxes, ...
                        'XData', [1 app.UIAxes.Position(3)], ...
                        'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
                case 2
                    % Display image and stretch to fill axes
                    I = imshow('Pleasure.png', 'Parent', app.UIAxes, ...
                        'XData', [1 759*app.imgStch], ...
                        'YData', [1 164*app.imgStch]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
                case 3
                    % Display image and stretch to fill axes
                    I = imshow('Arousal.png', 'Parent', app.UIAxes, ...
                        'XData', [1 759*app.imgStch], ...
                        'YData', [1 164*app.imgStch]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
                case 4
                    % Display image and stretch to fill axes
                    I = imshow('Dominance.png', 'Parent', app.UIAxes, ...
                        'XData', [1 759*app.imgStch], ...
                        'YData', [1 164*app.imgStch]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
                otherwise
                    % Display image and stretch to fill axes
                    I = imshow('wear_example1.jpg', 'Parent', app.UIAxes, ...
                        'XData', [1 app.UIAxes.Position(3)], ...
                        'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
            end
        end
    
        function UpdateInstructions(app)
            switch app.page
                case 1
                    app.Instructions.Text = "Place the Emotiv Insight on your head";
                case 2
                    app.Instructions.Text = "SAM: Valence";
                case 3
                    app.Instructions.Text = "SAM: Arousal";
                case 4
                    app.Instructions.Text = "SAM: Dominance";
                otherwise
                    app.Instructions.Text = "THE END";
                    app.ButtonGroup.Visible = 'off';
                    app.NextButton.Visible = 'off';
            end
        end
    
        function UpdatePage(app)
            UpdatePageTitle(app);
            UpdateUIAxes(app);
            UpdateInstructions(app);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            % Display image and stretch to fill axes
            I = imshow('wear_example1.jpg', 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            % Turn off back button
            app.BackButton.Visible = 'off';
            
            % Turn off radio buttons
            app.ButtonGroup.Visible = 'off';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.page = app.page + 1;
            app.PageLabel.Text = "Page: " + num2str(app.page);
            app.BackButton.Visible = 'on';
            app.ButtonGroup.Visible = 'on';
            UpdatePage(app);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.page = app.page - 1;
            if app.page == 1
                app.BackButton.Visible = 'off';
                app.ButtonGroup.Visible = 'off';
            else
                app.BackButton.Visible = 'on';
                app.ButtonGroup.Visible = 'on';
            end
            UpdatePage(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PageTitle = uilabel(app.UIFigure);
            app.PageTitle.Text = 'RoboDoc: EEG';
            app.Instructions = uilabel(app.UIFigure);
            app.Instructions.Text = 'Place the Emotiv Insight on your head';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.PageLabel = uilabel(app.UIFigure);
            app.PageLabel.Text = 'Page: 1';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.Button_1 = uiradiobutton(app.ButtonGroup);
            app.Button_1.Text = '';
            app.Button_1.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '';
            app.Button_7 = uiradiobutton(app.ButtonGroup);
            app.Button_7.Text = '';
            app.Button_8 = uiradiobutton(app.ButtonGroup);
            app.Button_8.Text = '';
            app.Button_9 = uiradiobutton(app.ButtonGroup);
            app.Button_9.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEG

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end